classdef BAR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Log                      matlab.ui.control.TextArea
        TabGroup                 matlab.ui.container.TabGroup
        DatabaseSearchTab        matlab.ui.container.Tab
        LoadFilesButton          matlab.ui.control.Button
        FindFilesButton          matlab.ui.control.Button
        TableFiles               matlab.ui.control.Table
        SearchTags               matlab.ui.control.EditField
        EditFieldLabel           matlab.ui.control.Label
        EquipmentDropDown        matlab.ui.control.DropDown
        SelectequipmentthedatawasrecordedfromLabel  matlab.ui.control.Label
        FileExtensionDropDown    matlab.ui.control.DropDown
        SelectfileextensionofthedatafilesLabel  matlab.ui.control.Label
        Directory                matlab.ui.control.EditField
        SelectDirectoryButton    matlab.ui.control.Button
        AnalysisTab              matlab.ui.container.Tab
        DataFigureDropDown       matlab.ui.control.DropDown
        DataFigureDropDownLabel  matlab.ui.control.Label
        RunAnalysisButton        matlab.ui.control.Button
        AnalysisDropDown         matlab.ui.control.DropDown
        AnalysisDropDownLabel    matlab.ui.control.Label
        FiguresTab               matlab.ui.container.Tab
        StatisticsTab            matlab.ui.container.Tab
        ExportTab                matlab.ui.container.Tab
        UITableExport            matlab.ui.control.Table
        ButtonExport             matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        AppAnalysis % Description
    end
    
    methods (Access = private)
        
        function files = filesearch(app, files, m_directory)

            m_files = dir(m_directory);
            m_tags = app.SearchTags.Value;
            if ~isempty(m_tags)
                m_tags = textscan(m_tags,'%s','Delimiter',',');
            end

            for i = 3:length(m_files)
                if isfolder([m_files(i).folder '\' m_files(i).name]) && ~strcmp(m_files(i).name(1), '.')
                    disp([m_files(i).folder '\' m_files(i).name])
                    files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = [];
                    else
                        n = length(files.name);
                    end
                    [~, ~, ext] = fileparts(m_files(i).name);
                    if strcmp(ext, ['.' app.FileExtensionDropDown.Value]) && (strcmp(m_tags, '') || contains(m_files(i).name, m_tags{1}))
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        files.bytes(n+1, 1) = m_files(i).bytes;
                    end
                end
                app.TableFiles.Data = struct2table(files);
                pause(0.001)
            end
            
        end
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['file search started'];
                case '002'
                    text{length(text) + 1,1} = ['file search complete, ' num2str(newText{1}) ' files found, ' num2str(ceil(newText{2}*1e-6)) ' MB total'];
                case '003'
                    text{length(text) + 1,1} = ['error reading h5APDM file: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['loading files started for: ' newText];
                case '005'
                    text{length(text) + 1,1} = ['loading files complete for: ' newText];
                case '006'
                    text{length(text) + 1,1} = ['results not found'];
                case '007'
                    text{length(text) + 1,1} = ['results exported'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
            
        end
        
        function config = loadConfig(app)
            % Find the path to the main folder.
            m_analysisPath = mfilename('fullpath');
            ind = strfind(m_analysisPath,'\');
            m_analysisPath(ind(end):end) = [];
            
            % Load the config file.
            config = readstruct([m_analysisPath '\config.xml']);
        end
        
    end
    
    methods (Access = public)
        
        function analysisComplete(app, data, analysis)
            setappdata(app.TableFiles,'data',data)
            
            m_resultsPath = mfilename('fullpath');
            ind = strfind(m_resultsPath,'\');
            m_resultsPath(ind(end):end) = [];
            save([m_resultsPath '\Results\' analysis], 'data', '-v7.3')
        end
        
        function exportResults(app, data, analysis)
            
            export = struct;

            if ~any(strcmp(fieldnames(data),'res'))
                printLog(app, '006', {''})
                return
            end
            for i = 1:length(analysis)
                signals = fieldnames(data.res.(analysis{i}));
                for j = 1:length(signals)
                    switch analysis{i}
                        case 'RQA'
                            export.name{j,1} = signals{j};
                            export.time{j,1} = data.res.(analysis{i}).(signals{j}).time;
                            export.dimensions{j,1} = data.res.(analysis{i}).(signals{j}).results.DIM;
                            export.embedding{j,1} = data.res.(analysis{i}).(signals{j}).results.EMB;
                            export.delay{j,1} = data.res.(analysis{i}).(signals{j}).results.DEL;
                            export.radius{j,1} = data.res.(analysis{i}).(signals{j}).results.RADIUS;
                            export.normalization{j,1} = data.res.(analysis{i}).(signals{j}).results.NORM;
                            export.zscore{j,1} = data.res.(analysis{i}).(signals{j}).results.ZSCORE;
                            export.length{j,1} = data.res.(analysis{i}).(signals{j}).results.Size;
                            export.rr{j,1} = data.res.(analysis{i}).(signals{j}).results.REC;
                            export.det{j,1} = data.res.(analysis{i}).(signals{j}).results.DET;
                            export.meanl{j,1} = data.res.(analysis{i}).(signals{j}).results.MeanL;
                            export.maxl{j,1} = data.res.(analysis{i}).(signals{j}).results.MaxL;
                            export.entropyl{j,1} = data.res.(analysis{i}).(signals{j}).results.EntrL;
                            export.lam{j,1} = data.res.(analysis{i}).(signals{j}).results.LAM;
                            export.meanv{j,1} = data.res.(analysis{i}).(signals{j}).results.MeanV;
                            export.maxv{j,1} = data.res.(analysis{i}).(signals{j}).results.MaxV;
                            export.entropyv{j,1} = data.res.(analysis{i}).(signals{j}).results.EntrV;
                            export.entropyw{j,1} = data.res.(analysis{i}).(signals{j}).results.EntrW;
                    end
                end
            end
            
            app.UITableExport.Data = struct2table(export);

            m_resultsPath = mfilename('fullpath');
            ind = strfind(m_resultsPath,'\');
            m_resultsPath(ind(end):end) = [];
            writetable(struct2table(export),[m_resultsPath '\Results\' 'Results_RQA.xlsx'])

            printLog(app, '007')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Find the path to the Analysis folder.
%             m_analysisPath = mfilename('fullpath');
%             ind = strfind(m_analysisPath,'\');
%             m_analysisPath(ind(end):end) = [];
%             m_analysisOptions = dir([m_analysisPath '\Analysis\']);
%             m_analysisOptions(1:2,:) = [];
%             % Set the list of analyses to the files found.
%             if iscell(m_analysisOptions)
%                 app.AnalysisDropDown.Items = m_analysisOptions.name;
%             else
%                 app.AnalysisDropDown.Items = {m_analysisOptions.name};
%             end

            % Load the config file.
            config = loadConfig(app);
            app.FileExtensionDropDown.Items = fieldnames(config.options.files);
            app.AnalysisDropDown.Items = config.analysis(1:2:end);
            
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            % Allow the user to select the parent directory.
            m_path = uigetdir(pwd,'Please select the data directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            app.Directory.Value = m_path;
        end

        % Button pushed function: FindFilesButton
        function FindFilesButtonPushed(app, event)
            
            % Pull the selected directory from the app text box.
            m_directory = app.Directory.Value;
            % Pull the selected file extension from the app dropdown.
            m_fileType = app.FileExtensionDropDown.Value;
            % Pull the equipment type from the app dropdown.
            m_equipmentType = app.EquipmentDropDown.Value;
            % Pull the search tags the user entered into the app text box.
            m_searchTags = app.SearchTags.Value;
            
            % Initialize an empty structure for the object to store the
            % file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            files = filesearch(app, files, m_directory);
            printLog(app, '002', {length(files.name);sum(files.bytes)})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);

        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)

            printLog(app, '004', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            data = struct;
            ind1 = 1;

            for i = 1:height(files)

                name = files.name(i);
                ind2 = strfind(name, '.');
                name{1}(ind2{end}:end) = [];

                switch [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value]
                    case 'h5APDM'
                        try
                            data.raw.(name{1}) = load_h5_APDM([files.folder{i} '\' files.name{i}]);
                        catch
                            printLog(app, '003', name{1})
                        end
                    case 'txtV3D'
                        try
                            data.raw.(name{1}) = load_txt_V3D([files.folder{i} '\' files.name{i}]);
                        catch
                            printLog(app, '003', name{1})
                        end
                    case 'matBAR'
                        try
                            data = load_mat_BAR([files.folder{i} '\' files.name{i}]);
                        catch
                            printLog(app, '003', name{1})
                        end
                end
            end
            
            setappdata(app.TableFiles,'data',data)

            printLog(app, '005', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');
            
            analysis = app.AnalysisDropDown.Value;
            
            config = loadConfig(app);
            ind = find(strcmp(config.analysis, analysis));
            
            app.AppAnalysis = feval(config.analysis{ind + 1}, app, data, analysis);
            
        end

        % Value changed function: FileExtensionDropDown
        function FileExtensionDropDownValueChanged(app, event)
            % Get the currently selected file extension.
            value = app.FileExtensionDropDown.Value;
            % Load the configuration file with the matching file extensions
            % and equipment names.
            config = loadConfig(app);
            % Get the file extension options.
            options = fieldnames(config.options.files);
            for i = 1:length(options)
                if strcmp(value, options{i})
                    app.EquipmentDropDown.Items = config.options.files.(options{i});
                    break
                end
            end
            
        end

        % Button pushed function: ButtonExport
        function ButtonExportPushed(app, event)
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSearchTab = uitab(app.TabGroup);
            app.DatabaseSearchTab.Title = 'Database Search';
            app.SelectDirectoryButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.Directory = uieditfield(app.DatabaseSearchTab, 'text');
            app.SelectfileextensionofthedatafilesLabel = uilabel(app.DatabaseSearchTab);
            app.SelectfileextensionofthedatafilesLabel.Text = {'Select file extension'; 'of the data files.'};
            app.FileExtensionDropDown = uidropdown(app.DatabaseSearchTab);
            app.FileExtensionDropDown.Items = {};
            app.FileExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @FileExtensionDropDownValueChanged, true);
            app.FileExtensionDropDown.Value = {};
            app.SelectequipmentthedatawasrecordedfromLabel = uilabel(app.DatabaseSearchTab);
            app.SelectequipmentthedatawasrecordedfromLabel.Text = {'Select equipment the'; 'data was recorded from.'};
            app.EquipmentDropDown = uidropdown(app.DatabaseSearchTab);
            app.EquipmentDropDown.Items = {};
            app.EquipmentDropDown.Value = {};
            app.EditFieldLabel = uilabel(app.DatabaseSearchTab);
            app.EditFieldLabel.Text = {'Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTags = uieditfield(app.DatabaseSearchTab, 'text');
            app.TableFiles = uitable(app.DatabaseSearchTab);
            app.TableFiles.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableFiles.RowName = {};
            app.FindFilesButton = uibutton(app.DatabaseSearchTab, 'push');
            app.FindFilesButton.ButtonPushedFcn = createCallbackFcn(app, @FindFilesButtonPushed, true);
            app.FindFilesButton.Text = 'Find Files';
            app.LoadFilesButton = uibutton(app.DatabaseSearchTab, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisDropDownLabel = uilabel(app.AnalysisTab);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.AnalysisTab);
            app.AnalysisDropDown.Items = {};
            app.AnalysisDropDown.Value = {};
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.DataFigureDropDownLabel = uilabel(app.AnalysisTab);
            app.DataFigureDropDownLabel.Text = 'Data Figure';
            app.DataFigureDropDown = uidropdown(app.AnalysisTab);
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ButtonExport = uibutton(app.ExportTab, 'push');
            app.ButtonExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonExportPushed, true);
            app.ButtonExport.Text = 'Format loaded results for export';
            app.UITableExport = uitable(app.ExportTab);
            app.UITableExport.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableExport.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end